classdef project_ph1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GainSlider                   matlab.ui.control.Slider
        GainSliderLabel              matlab.ui.control.Label
        PowerSwitch                  matlab.ui.control.Switch
        PowerSwitchLabel             matlab.ui.control.Label
        CenterFrequencyMHzEditField  matlab.ui.control.NumericEditField
        FrequencyEditLabel           matlab.ui.control.Label
        WaterfallAxes                matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        SpectrumAxes                 matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
    rx              % RTL-SDR receiver
    running = false % loop running
    waterfallData   % waterfall matrix
end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)


    if app.PowerSwitch.Value == "On"
        app.running = true;

        % --- Create RTL-SDR Receiver ---
        app.rx = comm.SDRRTLReceiver( ...
            'RadioAddress','1', ...
            'CenterFrequency', app.CenterFrequencyMHzEditField.Value * 1e6, ...
            'SampleRate', 2.4e6, ...
            'SamplesPerFrame', 16000, ...
            'OutputDataType','double');

        while app.running && isvalid(app.PowerSwitch)
            [data,len] = app.rx();

            if len > 0
                
                %% ======== FFT Spectrum ========
                fftData = abs(fftshift(fft(data)));
                N  = length(fftData);
                sr = app.rx.SampleRate;
                f  = linspace(-sr/2, sr/2, N)/1e6 + app.CenterFrequencyMHzEditField.Value;

                plot(app.SpectrumAxes, f, fftData, "Color",[0 0.4 1])
                xlabel(app.SpectrumAxes,"Frequency (MHz)")
                ylabel(app.SpectrumAxes,"Magnitude")
                title(app.SpectrumAxes,"Spectrum")
                grid(app.SpectrumAxes,'on')
                drawnow limitrate;


               %% ======== PSD DISPLAY  ========
window = hamming(1024);
noverlap = 512;
nfft = 2048;

[pxx,ff] = pwelch(data, window, noverlap, nfft, sr, 'centered');

plot(app.UIAxes2, ff/1e6 + app.CenterFrequencyMHzEditField.Value, 10*log10(pxx), ...
     "Color",[0.9 0.2 0.1], "LineWidth",1.2)

xlabel(app.UIAxes2,"Frequency (MHz)")
ylabel(app.UIAxes2,"Power (dB)")
title(app.UIAxes2,"Power Spectral Density (Welch Method)")
grid(app.UIAxes2,'on')

                %% ======== Waterfall DISPLAY ========
app.waterfallData = [app.waterfallData; fftData.'];

if size(app.waterfallData,1) > 200
    app.waterfallData(1,:) = [];
end

imagesc(app.WaterfallAxes, f, 1:size(app.waterfallData,1), app.waterfallData);
axis(app.WaterfallAxes,'xy');
xlabel(app.WaterfallAxes,'Frequency (MHz)');
ylabel(app.WaterfallAxes,'Time');
colormap(app.WaterfallAxes,'jet');

            end
        end

    else
        % Switch OFF
        app.running = false;
        if ~isempty(app.rx)
            release(app.rx);
        end
    end


        end

        % Value changed function: CenterFrequencyMHzEditField
        function CenterFrequencyMHzEditFieldValueChanged(app, event)
          
    if ~isempty(app.rx)
        app.rx.CenterFrequency = app.CenterFrequencyMHzEditField.Value * 1e6;
    end


        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
             if ~isempty(app.rx)
        try
            app.rx.TunerGain = app.GainSlider.Value;
        catch
            
        end
    end
            
        end

        % Button down function: SpectrumAxes
        function SpectrumAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'bone');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrumAxes = uiaxes(app.UIFigure);
            title(app.SpectrumAxes, 'SpectrumAxes')
            xlabel(app.SpectrumAxes, 'X')
            ylabel(app.SpectrumAxes, 'Y')
            zlabel(app.SpectrumAxes, 'Z')
            app.SpectrumAxes.ButtonDownFcn = createCallbackFcn(app, @SpectrumAxesButtonDown, true);
            colormap(app.SpectrumAxes, 'bone')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PSD')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            colormap(app.UIAxes2, 'bone')
            app.WaterfallAxes = uiaxes(app.UIFigure);
            title(app.WaterfallAxes, 'WaterfallAxes')
            xlabel(app.WaterfallAxes, 'X')
            ylabel(app.WaterfallAxes, 'Y')
            zlabel(app.WaterfallAxes, 'Z')
            colormap(app.WaterfallAxes, 'bone')
            app.FrequencyEditLabel = uilabel(app.UIFigure);
            app.FrequencyEditLabel.Text = 'Center Frequency (MHz)';
            app.CenterFrequencyMHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterFrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyMHzEditFieldValueChanged, true);
            app.CenterFrequencyMHzEditField.Tag = 'FrequencyEdit';
            app.CenterFrequencyMHzEditField.Value = 100;
            app.PowerSwitchLabel = uilabel(app.UIFigure);
            app.PowerSwitchLabel.Interpreter = 'tex';
            app.PowerSwitchLabel.Text = 'PowerSwitch';
            app.PowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.GainSliderLabel = uilabel(app.UIFigure);
            app.GainSliderLabel.Text = 'GainSlider';
            app.GainSlider = uislider(app.UIFigure);
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_ph1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end